classdef Program_10_2 < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure                        matlab.ui.Figure
		lblQueue                        matlab.ui.control.Label
		tfDuration                      matlab.ui.control.NumericEditField
		DurationsEditFieldLabel_2       matlab.ui.control.Label
		tfRedDuration                   matlab.ui.control.NumericEditField
		RedDurationsEditFieldLabel_2    matlab.ui.control.Label
		tfGreenDuration                 matlab.ui.control.NumericEditField
		GreenDurationsEditFieldLabel_2  matlab.ui.control.Label
		tfCarProbability                matlab.ui.control.NumericEditField
		CarProbabilityEditFieldLabel_2  matlab.ui.control.Label
		btnRunSimulation                matlab.ui.control.Button
		lblT                            matlab.ui.control.Label
		TrafficLightButtonGroup         matlab.ui.container.ButtonGroup
		btnGreenLight                   matlab.ui.control.ToggleButton
		btnYellowLight                  matlab.ui.control.ToggleButton
		btnRedLight                     matlab.ui.control.ToggleButton
	end

	
	methods (Access = private)
		
		%modified from function in textbook. runs one cycle at a "green" light
		function go(app)
 			app.UIFigure.UserData.GTIMER = app.UIFigure.UserData.GTIMER + 1; % advance green timer
 			app.UIFigure.UserData.CARS = app.UIFigure.UserData.CARS- 8; % let 8 cars through
 			if app.UIFigure.UserData.CARS < 0
				app.UIFigure.UserData.CARS = 0; %there may have been < 8
			end
 			prq(app)% display queue of cars
 			
			if app.UIFigure.UserData.GTIMER == app.UIFigure.UserData.GREEN %reset timer and change state if it has reached the end of the interval
				app.UIFigure.UserData.LIGHTS = 'R';
				app.UIFigure.UserData.GTIMER = 0;
			end
		end

		%modified from function in textbook. runs one cycle at a "red" light
		function  stop(app)
 			app.UIFigure.UserData.RTIMER = app.UIFigure.UserData.RTIMER + 1;
 			prq(app)
 			if app.UIFigure.UserData.RTIMER == app.UIFigure.UserData.RED
 				app.UIFigure.UserData.LIGHTS = 'G';
 				app.UIFigure.UserData.RTIMER = 0;
			end
		end

		
		%modified from function in textbook. handles data display for simulation
		function prq(app)
 			%fprintf('%3.0f', app.UIFigure.UserData.T );
 			if app.UIFigure.UserData.LIGHTS == 'R'
				app.btnRedLight.BackgroundColor = "Red";
				app.btnYellowLight.BackgroundColor = "#FFFFFF";
				app.btnGreenLight.BackgroundColor = "#FFFFFF";
			else
				app.btnRedLight.BackgroundColor = "#FFFFFF";
				app.btnYellowLight.BackgroundColor = "#FFFFFF";
				app.btnGreenLight.BackgroundColor = "Green";
			end
			queue_text = '';
 			for i = 1:app.UIFigure.UserData.CARS
				queue_text = sprintf('%s *', queue_text);
			end
			app.lblQueue.Text = queue_text;
			app.lblT.Text = sprintf('%i', app.UIFigure.UserData.T);
		end

		%assign inputs to variables in UserData, input fields are locked during the simulation
		function collectInputs(app)
			app.UIFigure.UserData.p = app.tfCarProbability.Value; %probability of a car arriving
				app.tfCarProbability.Editable = "off";
			app.UIFigure.UserData.GREEN = app.tfGreenDuration.Value; %period lights are green
				app.tfGreenDuration.Editable = "off";
			app.UIFigure.UserData.RED = app.tfRedDuration.Value; %period lights are red
				app.tfRedDuration.Editable = "off";
			app.UIFigure.UserData.n = app.tfDuration.Value; %number of 10 second periods
				app.tfDuration.Editable = "off";
		end

		%handles resetting data from current simulation to allow it to be run again in the same window
		function reset(app)
			app.UIFigure.UserData = {};
			startupFcn(app);
			%release inputs
			app.tfCarProbability.Editable = "on";
			app.tfGreenDuration.Editable = "on";
			app.tfRedDuration.Editable = "on";
			app.tfDuration.Editable = "on";
		end

		%handles display of the plot after simulation has been run
		function displayPlot(app)
			figure('Name','Traffic Light Analysis')
			plot(1:app.UIFigure.UserData.T, app.UIFigure.UserData.CARS_QUEUED_EACH_INTERVAL, '-o')
			title('Traffic Light Analysis')
			xlabel('Time')
			ylabel('# Cars')
			axis([0 inf 0 inf])
		end

	end

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			app.UIFigure.UserData.CARS = 0; %cars in queue
			app.UIFigure.UserData.GTIMER = 0; %timer for green lights
			app.UIFigure.UserData.LIGHTS = 'R'; %color of lights
			app.UIFigure.UserData.RTIMER = 0; %timer for red lights
			app.UIFigure.UserData.CARS_QUEUED_EACH_INTERVAL = [];
		end

		% Button pushed function: btnRunSimulation
		function btnRunSimulationPushed(app, event)
			app.btnRedLight.BackgroundColor = "Red";%begin as red state
			collectInputs(app) %get user parameters, editable fields are locked while the simulation is running
			app.btnRunSimulation.Enable = "off";%disable button while simulation is running

			for T = 1:app.UIFigure.UserData.n
				app.UIFigure.UserData.T = T;
 				r = rand(1,10);
 				app.UIFigure.UserData.CARS = app.UIFigure.UserData.CARS + sum(r < app.UIFigure.UserData.p); % cars arriving in 10 seconds
				if app.UIFigure.UserData.LIGHTS == 'G'
					go(app)
 				else
					stop(app)
				end
				pause(1)%wait one second between iterations
				prq(app)%update the label and graphic for the GUI
				%store the number of cars at the end of this iteration
				app.UIFigure.UserData.CARS_QUEUED_EACH_INTERVAL(T) = app.UIFigure.UserData.CARS;
			end

			%display chart
			displayPlot(app)

			%reset variables to allow simulation to be run again, input fields will be re-enabled in this function
			reset(app)
			app.btnRunSimulation.Enable = "on";%re-enable button
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.TrafficLightButtonGroup = uibuttongroup(app.UIFigure);
			app.TrafficLightButtonGroup.Title = 'Traffic Light';
			app.btnRedLight = uitogglebutton(app.TrafficLightButtonGroup);
			app.btnRedLight.Text = '';
			app.btnYellowLight = uitogglebutton(app.TrafficLightButtonGroup);
			app.btnYellowLight.Text = '';
			app.btnYellowLight.Value = true;
			app.btnGreenLight = uitogglebutton(app.TrafficLightButtonGroup);
			app.btnGreenLight.Text = '';
			app.lblT = uilabel(app.UIFigure);
			app.lblT.Text = '0';
			app.btnRunSimulation = uibutton(app.UIFigure, 'push');
			app.btnRunSimulation.ButtonPushedFcn = createCallbackFcn(app, @btnRunSimulationPushed, true);
			app.btnRunSimulation.Text = 'Run Simulation';
			app.CarProbabilityEditFieldLabel_2 = uilabel(app.UIFigure);
			app.CarProbabilityEditFieldLabel_2.Text = 'Car Probability';
			app.tfCarProbability = uieditfield(app.UIFigure, 'numeric');
			app.tfCarProbability.Limits = [0 Inf];
			app.tfCarProbability.Value = 0.3;
			app.GreenDurationsEditFieldLabel_2 = uilabel(app.UIFigure);
			app.GreenDurationsEditFieldLabel_2.Text = 'Green Duration (s)';
			app.tfGreenDuration = uieditfield(app.UIFigure, 'numeric');
			app.tfGreenDuration.Limits = [0 Inf];
			app.tfGreenDuration.ValueDisplayFormat = '%i';
			app.tfGreenDuration.Value = 2;
			app.RedDurationsEditFieldLabel_2 = uilabel(app.UIFigure);
			app.RedDurationsEditFieldLabel_2.Text = 'Red Duration (s)';
			app.tfRedDuration = uieditfield(app.UIFigure, 'numeric');
			app.tfRedDuration.Limits = [0 Inf];
			app.tfRedDuration.ValueDisplayFormat = '%i';
			app.tfRedDuration.Value = 4;
			app.DurationsEditFieldLabel_2 = uilabel(app.UIFigure);
			app.DurationsEditFieldLabel_2.Text = 'Duration(s)';
			app.tfDuration = uieditfield(app.UIFigure, 'numeric');
			app.tfDuration.Limits = [0 Inf];
			app.tfDuration.ValueDisplayFormat = '%i';
			app.tfDuration.Value = 48;
			app.lblQueue = uilabel(app.UIFigure);
			app.lblQueue.Text = '';
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = Program_10_2

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end