classdef Program_09_2 < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure            matlab.ui.Figure
		btnClearPlots       matlab.ui.control.Button
		btnPlotExponential  matlab.ui.control.Button
		btnPlotLinear       matlab.ui.control.Button
		pltExponential      matlab.ui.control.UIAxes
		pltLinear           matlab.ui.control.UIAxes
	end

	% Callbacks that handle component events
	methods (Access = private)

		% Callback function: btnPlotLinear, pltLinear
		function pltLinearButtonDown(app, event)
			%call user function labelGUIPlot to set parameters
			labelGUIPlot(app.pltLinear, 'Linear Plot', 'X data', 'Y data')
			fplot(@(x)x, [0, 10], 'Parent', app.pltLinear)
		end

		% Button pushed function: btnPlotExponential
		function btnPlotExponentialButtonPushed(app, event)
			%call user function labelGUIPlot to set parameters
			labelGUIPlot(app.pltExponential, 'Exponential Plot', 'X data', 'Y data')
			fplot(@(x)x^2, [0, 10], 'Parent', app.pltExponential)
		end

		% Button pushed function: btnClearPlots
		function btnClearPlotsButtonPushed(app, event)
			%clear each plot and use labelGUIPlot to set default values
			cla(app.pltLinear)
			labelGUIPlot(app.pltLinear, '', '', '')
			cla(app.pltExponential)
			labelGUIPlot(app.pltExponential, '', '', '')
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.pltLinear = uiaxes(app.UIFigure);
			app.pltLinear.ButtonDownFcn = createCallbackFcn(app, @pltLinearButtonDown, true);
			app.pltExponential = uiaxes(app.UIFigure);
			app.btnPlotLinear = uibutton(app.UIFigure, 'push');
			app.btnPlotLinear.ButtonPushedFcn = createCallbackFcn(app, @pltLinearButtonDown, true);
			app.btnPlotLinear.Text = 'Plot Linear';
			app.btnPlotExponential = uibutton(app.UIFigure, 'push');
			app.btnPlotExponential.ButtonPushedFcn = createCallbackFcn(app, @btnPlotExponentialButtonPushed, true);
			app.btnPlotExponential.Text = 'Plot Exponential';
			app.btnClearPlots = uibutton(app.UIFigure, 'push');
			app.btnClearPlots.ButtonPushedFcn = createCallbackFcn(app, @btnClearPlotsButtonPushed, true);
			app.btnClearPlots.Text = 'Clear Plots';
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = Program_09_2

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end