classdef Program_09_3 < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure         matlab.ui.Figure
		ddPlotSelection  matlab.ui.control.DropDown
		btnGridOnOff     matlab.ui.control.Button
		pltThickens      matlab.ui.control.UIAxes
	end

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			% Create a 2D mesh with x and y from -8 to 8 in steps of 0.5
			[x,y] = meshgrid(-8:.5: 8);
			r = sqrt(x.^2+y.^2) + eps;
			z = sin(r)./r;
			%store data in the UserData struct
			app.UIFigure.UserData.x=x;
			app.UIFigure.UserData.y=y;
			app.UIFigure.UserData.r = r;
			app.UIFigure.UserData.z = z;
		end

		% Value changed function: ddPlotSelection
		function ddPlotSelectionValueChanged(app, event)
			value = app.ddPlotSelection.Value;

			switch(value) %switch for the value selected in ddPlotSelection
				case 'choose' %clear the plot and reset labels
					cla(app.pltThickens)
					labelGUIPlot(app.pltThickens, '', '', '')
				case 'plot3' %set labels and plot the data using plot3
					labelGUIPlot(app.pltThickens, '', 'X', 'Y', 'Z')
					plot3(app.pltThickens, app.UIFigure.UserData.x, app.UIFigure.UserData.y, app.UIFigure.UserData.z)
				case 'waterfall' %set labels and plot the data using waterfall
					labelGUIPlot(app.pltThickens, '', 'X', 'Y', 'Z')
					waterfall(app.pltThickens, app.UIFigure.UserData.x, app.UIFigure.UserData.y, app.UIFigure.UserData.z)
				case 'contour3' %set labels and plot the data using contour3
					labelGUIPlot(app.pltThickens, '', 'X', 'Y', 'Z')
					contour3(app.pltThickens, app.UIFigure.UserData.z, 50)
				case 'contour' %set labels and plot the data using contour
					labelGUIPlot(app.pltThickens, '', 'X', 'Y', 'Z')
					contour(app.pltThickens, app.UIFigure.UserData.z, 50)
			end
		end

		% Button pushed function: btnGridOnOff
		function btnGridOnOffPushed(app, event)
			%invert the current status of the grid lines for each dimensional axis
			app.pltThickens.XGrid = ~app.pltThickens.XGrid;
			app.pltThickens.YGrid = ~app.pltThickens.YGrid;
			app.pltThickens.ZGrid = ~app.pltThickens.ZGrid;
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.pltThickens = uiaxes(app.UIFigure);
			app.btnGridOnOff = uibutton(app.UIFigure, 'push');
			app.btnGridOnOff.ButtonPushedFcn = createCallbackFcn(app, @btnGridOnOffPushed, true);
			app.btnGridOnOff.Text = 'Grid On/Off';
			app.ddPlotSelection = uidropdown(app.UIFigure);
			app.ddPlotSelection.Items = {'choose', 'plot3', 'waterfall', 'contour3', 'contour'};
			app.ddPlotSelection.ValueChangedFcn = createCallbackFcn(app, @ddPlotSelectionValueChanged, true);
			app.ddPlotSelection.Value = 'choose';
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = Program_09_3

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end