classdef Program_09_4 < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure             matlab.ui.Figure
		imgFibonacci         matlab.ui.control.Image
		tfAnswer             matlab.ui.control.NumericEditField
		btnExecute           matlab.ui.control.Button
		lblOperandB          matlab.ui.control.Label
		lblEquals            matlab.ui.control.Label
		lblAnswer            matlab.ui.control.Label
		lblOperandA          matlab.ui.control.Label
		ddOperatorSelection  matlab.ui.control.DropDown
		tfOperandB           matlab.ui.control.NumericEditField
		tfOperandA           matlab.ui.control.NumericEditField
	end

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			app.UIFigure.UserData.indecision_count = 0;
		end

		% Value changed function: ddOperatorSelection
		function ddOperatorSelectionValueChanged(app, event)
			%increment the "indecision counter" by one every time the operator is changed. this value is reset every time the execute button is pushed
			%but if it exceeds 5 without executing an operation the button is hidden and needs to be unlocked by entering two sequential fibonacci
			%numbers (in order) into fields A and B. The unlock logic is contained within the tfOperandValueChanged callback function
			app.UIFigure.UserData.indecision_count = app.UIFigure.UserData.indecision_count +1;
			if(app.UIFigure.UserData.indecision_count > 5)
				%hide the button and enable a judgemental portrait of Leonardo Fibonacci
				app.btnExecute.Visible = "off";
				app.imgFibonacci.Visible = "on";
			end
		end

		% Button pushed function: btnExecute
		function btnExecuteButtonPushed(app, event)
			value = app.ddOperatorSelection.Value; %get the value of the dropdown menu
			app.UIFigure.UserData.indecision_count = 0; %update the indecision counter


			%switch on the value of the dropdown, perform the correct operation on the two operands or clear both fields
			switch(value)
				case 'choose operator'
					app.tfAnswer.Value = [];
					app.tfOperandA.Value = [];
					app.tfOperandB.Value = [];
				case '+'
					app.tfAnswer.Value = app.tfOperandA.Value + app.tfOperandB.Value;
				case '-'
					app.tfAnswer.Value = app.tfOperandA.Value - app.tfOperandB.Value;
				case '*'
					app.tfAnswer.Value = app.tfOperandA.Value * app.tfOperandB.Value;
				case '/'
					if(app.tfOperandB.Value == 0)
						app.tfAnswer.Value = [];
					else
						app.tfAnswer.Value = app.tfOperandA.Value / app.tfOperandB.Value;
					end
			end
		end

		% Value changed function: tfOperandA, tfOperandB
		function tfOperandValueChanged(app, event)
			%only compute fibonacci numbers if the execute button is not already visible
			if(app.btnExecute.Visible == "off")
				iterator = 0;
				result = 0;
				operand_a = app.tfOperandA.Value;
				operand_b = app.tfOperandB.Value;
				%calculate fibonacci numbers until one equals or exceeds operand_a
				while(result < operand_a)
					iterator = iterator +1;
					result = fibonacci(iterator);
				end
				%check if the calculated value is equal to operand_a
				if(result == operand_a)
					%calculate the next number in the series
					result_2 = fibonacci(iterator+1);
					%make the execute button visible if operand_b matches for the next number in the sequence or if
					% operand_a and operand_b match one of the weird conditions around 1 and 2
					if(result_2 == operand_b) | ((operand_a == 1) & ((operand_b == 1) | operand_b == 2)) 
						app.UIFigure.UserData.indecision_count = 0;
						%hide Fibonacci and re-enable the execute button
						app.btnExecute.Visible = "on";
						app.imgFibonacci.Visible = "off";
					end
				end
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			% Get the file path for locating images
			pathToMLAPP = fileparts(mfilename('fullpath'));
			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.tfOperandA = uieditfield(app.UIFigure, 'numeric');
			app.tfOperandA.AllowEmpty = 'on';
			app.tfOperandA.ValueChangedFcn = createCallbackFcn(app, @tfOperandValueChanged, true);
			app.tfOperandA.Value = [];
			app.tfOperandB = uieditfield(app.UIFigure, 'numeric');
			app.tfOperandB.AllowEmpty = 'on';
			app.tfOperandB.ValueChangedFcn = createCallbackFcn(app, @tfOperandValueChanged, true);
			app.tfOperandB.Value = [];
			app.ddOperatorSelection = uidropdown(app.UIFigure);
			app.ddOperatorSelection.Items = {'choose operator', '+', '-', '*', '/'};
			app.ddOperatorSelection.ValueChangedFcn = createCallbackFcn(app, @ddOperatorSelectionValueChanged, true);
			app.ddOperatorSelection.Value = 'choose operator';
			app.lblOperandA = uilabel(app.UIFigure);
			app.lblOperandA.Text = 'a';
			app.lblAnswer = uilabel(app.UIFigure);
			app.lblAnswer.Text = 'Answer';
			app.lblEquals = uilabel(app.UIFigure);
			app.lblEquals.Text = '=';
			app.lblOperandB = uilabel(app.UIFigure);
			app.lblOperandB.Text = 'b';
			app.btnExecute = uibutton(app.UIFigure, 'push');
			app.btnExecute.ButtonPushedFcn = createCallbackFcn(app, @btnExecuteButtonPushed, true);
			app.btnExecute.Text = 'Execute';
			app.tfAnswer = uieditfield(app.UIFigure, 'numeric');
			app.tfAnswer.ValueDisplayFormat = '%11.2f';
			app.tfAnswer.AllowEmpty = 'on';
			app.tfAnswer.Editable = 'off';
			app.tfAnswer.Value = [];
			app.imgFibonacci = uiimage(app.UIFigure);
			app.imgFibonacci.Visible = 'off';
			app.imgFibonacci.ImageSource = fullfile(pathToMLAPP, 'images', 'fibonacci.jpg');
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = Program_09_4

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end