classdef Program_09_6 < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure              matlab.ui.Figure
		lblLineWidth          matlab.ui.control.Label
		lblLineColor          matlab.ui.control.Label
		ddLineColor           matlab.ui.control.DropDown
		sldrLineWidth         matlab.ui.control.Slider
		LineStyleButtonGroup  matlab.ui.container.ButtonGroup
		btnDashDotted         matlab.ui.control.RadioButton
		btnDotted             matlab.ui.control.RadioButton
		btnDashed             matlab.ui.control.RadioButton
		btnSolid              matlab.ui.control.RadioButton
		pltDevices            matlab.ui.control.UIAxes
	end

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			%update the plot with the default values from the inputs
			UpdatePlot(app, app.pltDevices);
		end

		% Callback function: LineStyleButtonGroup, ddLineColor,
		% ddLineColor, 
		% ...and 2 other components
		function UpdatePlot(app, event)
			%get the current values of all three inputs
			drop_down_selection = app.ddLineColor.Value;
			line_width_selection = app.sldrLineWidth.Value;
			line_style_selection = app.LineStyleButtonGroup.SelectedObject.Text;
			
			%switch on the text of the selected radio button to get the correct input for the LineStyle parameter
			switch(line_style_selection)
				case 'Solid'
					line_style = '-';
				case 'Dashed'
					line_style = '--';
				case 'Dotted'
					line_style = ':';
				case 'Dash Dotted'
					line_style = '-.';
			end

			%if the black or default options are selected, use a black line color
			if(strcmp(drop_down_selection, 'Black') || strcmp(drop_down_selection, 'Choose Color'))
				line_color = 'k';
			else
				%for all other colors, the first character of the selection text matches the intended color when shifted to lowercase
				line_color = lower(drop_down_selection(1));
			end
	
			%plot the function with the calculated parameters. the value of line_width_selection is valid for the LineWidth parameter
			fplot(app.pltDevices, @(x)cos(x), [0 20], 'Color', line_color, 'LineStyle', line_style, 'LineWidth', line_width_selection);
			
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.pltDevices = uiaxes(app.UIFigure);
			title(app.pltDevices, 'Cos(x)')
			xlabel(app.pltDevices, 'X')
			ylabel(app.pltDevices, 'Cos(x)')
			app.pltDevices.XLim = [0 20];
			app.pltDevices.YLim = [-1 1];
			app.pltDevices.XTick = [0 2 4 6 8 10 12 14 16 18 20];
			app.pltDevices.XTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'; '12'; '14'; '16'; '18'; '20'};
			app.pltDevices.YTick = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
			app.pltDevices.YTickLabel = {'-1'; '-0.8'; '-0.6'; '-0.4'; '-0.2'; '0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
			app.LineStyleButtonGroup = uibuttongroup(app.UIFigure);
			app.LineStyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
			app.LineStyleButtonGroup.Title = 'Line Style';
			app.btnSolid = uiradiobutton(app.LineStyleButtonGroup);
			app.btnSolid.Text = 'Solid';
			app.btnSolid.Value = true;
			app.btnDashed = uiradiobutton(app.LineStyleButtonGroup);
			app.btnDashed.Text = 'Dashed';
			app.btnDotted = uiradiobutton(app.LineStyleButtonGroup);
			app.btnDotted.Text = 'Dotted';
			app.btnDashDotted = uiradiobutton(app.LineStyleButtonGroup);
			app.btnDashDotted.Text = 'Dash Dotted';
			app.sldrLineWidth = uislider(app.UIFigure);
			app.sldrLineWidth.Limits = [0.5 10];
			app.sldrLineWidth.MajorTicks = [0.5 2.5 4.5 6.5 8.5 10];
			app.sldrLineWidth.ValueChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
			app.sldrLineWidth.ValueChangingFcn = createCallbackFcn(app, @UpdatePlot, true);
			app.sldrLineWidth.Value = 0.5;
			app.ddLineColor = uidropdown(app.UIFigure);
			app.ddLineColor.Items = {'Choose Color', 'Red', 'Green', 'Blue', 'Cyan', 'Magenta', 'Yellow', 'Black'};
			app.ddLineColor.DropDownOpeningFcn = createCallbackFcn(app, @UpdatePlot, true);
			app.ddLineColor.ValueChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
			app.ddLineColor.Value = 'Choose Color';
			app.lblLineColor = uilabel(app.UIFigure);
			app.lblLineColor.Text = 'Line Color';
			app.lblLineWidth = uilabel(app.UIFigure);
			app.lblLineWidth.Text = 'Line Width';
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = Program_09_6

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end